classdef FIRFilter < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        FIRUIFigure        matlab.ui.Figure
        outputTimeFig      matlab.ui.control.UIAxes
        outputSpectrumFig  matlab.ui.control.UIAxes
        Button             matlab.ui.control.Button
        outputPhaseFig     matlab.ui.control.UIAxes
        Label              matlab.ui.control.Label
        EditField          matlab.ui.control.NumericEditField
        Label_2            matlab.ui.control.Label
        EditField_2        matlab.ui.control.NumericEditField
    end


    properties (Access = public)
        callingApp % Description
        
        % 输出信号
        outputSig
        
        % 重叠保留法点数
        pnum
        
        % 暂停时间
        pauseTime
        
    end

    properties (Access = private)
    end

    methods (Access = public)
    
        function [] = updatePointNum(app)
            % 更新重叠保留法点数
            app.pnum = app.EditField.Value; 
           
            % 更新秒数
            app.pauseTime = app.EditField_2.Value;
            
            app.outputSig = overlapSave(app.callingApp.testSig, app.callingApp.filterSig,app.pnum,app.pauseTime);
                        % 更新数据
            N = length(app.outputSig); % 原信号点数
            n  = linspace(0,N-1,N);
            fft_y = fft(app.outputSig); % 计算fft
            
            % n = linspace(0 , N-1,N); % 第n点
            w = 2*pi*n./N; % 角频率(未归一化)
            plot(app.outputTimeFig, app.outputSig);
            plot(app.outputSpectrumFig, w/pi,(abs(fft_y)));
            plot(app.outputPhaseFig, w/pi,unwrap(angle(fft_y)));
            app.callingApp.outputSig = app.outputSig;
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, main)
            app.callingApp = main;
            
            pointChangeCallback(app);


        end

        % Button pushed function: Button
        function playOutputSig(app, event)
            sound(app.outputSig,44100);
        end

        % Value changed function: EditField
        function pointChangeCallback(app, event)

            updatePointNum(app);
        end

        % Value changed function: EditField_2
        function secondChangeCallback(app, event)
   
             updatePointNum(app);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.FIRUIFigure = uifigure;
            app.FIRUIFigure.Name = 'FIR 滤波结果';
            app.outputTimeFig = uiaxes(app.FIRUIFigure);
            title(app.outputTimeFig, '时域')
            xlabel(app.outputTimeFig, 't')
            ylabel(app.outputTimeFig, '信号大小')
            app.outputSpectrumFig = uiaxes(app.FIRUIFigure);
            title(app.outputSpectrumFig, '幅度响应')
            xlabel(app.outputSpectrumFig, '归一化角频率 ω (x\times \pi rads/sample)')
            ylabel(app.outputSpectrumFig, '幅频衰减(dB)')
            app.outputSpectrumFig.XLim = [0 1];
            app.Button = uibutton(app.FIRUIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @playOutputSig, true);
            app.Button.Text = '播放';
            app.outputPhaseFig = uiaxes(app.FIRUIFigure);
            title(app.outputPhaseFig, '相位')
            xlabel(app.outputPhaseFig, '归一化角频率 ω (x\times \pi rads/sample)')
            ylabel(app.outputPhaseFig, '相位 ')
            app.outputPhaseFig.XLim = [0 1];
            app.Label = uilabel(app.FIRUIFigure);
            app.Label.Text = '重叠保留法分块点数';
            app.EditField = uieditfield(app.FIRUIFigure, 'numeric');
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @pointChangeCallback, true);
            app.EditField.Value = 512;
            app.Label_2 = uilabel(app.FIRUIFigure);
            app.Label_2.Text = '刷新秒数';
            app.EditField_2 = uieditfield(app.FIRUIFigure, 'numeric');
            app.EditField_2.ValueChangedFcn = createCallbackFcn(app, @secondChangeCallback, true);
            app.EditField_2.Value = 1;
        end
    end

    methods (Access = public)

        % Construct app
        function app = FIRFilter(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.FIRUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.FIRUIFigure)
        end
    end
end