classdef FIRfilterDesignApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        FIRUIFigure          matlab.ui.Figure
        filterSpectrumFig    matlab.ui.control.UIAxes
        left_Label           matlab.ui.control.Label
        leftOmegaEditField   matlab.ui.control.NumericEditField
        right_Label          matlab.ui.control.Label
        rightOmegaEditField  matlab.ui.control.NumericEditField
        FIRLabel             matlab.ui.control.Label
        Label                matlab.ui.control.Label
        DropDown             matlab.ui.control.DropDown
        DropDown_2           matlab.ui.control.DropDown
        filterPhaseFig       matlab.ui.control.UIAxes
        Button               matlab.ui.control.Button
        Button_2             matlab.ui.control.Button
    end


    properties (Access = public)
        T % T
        
        % 滤波器类型
        filterPassType % 滤波类型 1: 低通 2:高通 3: 带通 4:带阻
        windowType % 窗口类型 1: 矩形窗 2: 三角窗 3: 汉宁窗 4: 哈明窗
        
        
        window
        % 数字指标
        lw % 左边截止频率
        rw % 右边截止频率
        wc % 3dB频率
        
        
        M % 
        N % 滤波器阶数
        
        callingApp % App
        
        
        b % H(Z) 参数
        % 频率响应与角频率
        freqResp % 频率响应
        freqResp_w % 角频率
    end

    methods (Access = public)
    
        
        function [] = updateFIRFilter(app)
            % 更新数字滤波器
            
            % 更新数字频率app.lw 
            app.lw = app.leftOmegaEditField.Value;
            app.rw = app.rightOmegaEditField.Value;
            app.wc = (app.lw+app.rw)/2;
            
            % app.yui=4*pi;

            % app.N = ceil(6.2/abs(app.rw - app.lw)); % 点数
            
            %　M=ceil(12*pi/B);
            % M=M+mod(M,2);
            % hn=fir1(M,wp,'stop',blackman(M+1));
            
            
            if app.windowType==1
                 app.M = ceil(4.0/abs(app.rw - app.lw)); % 点数
                 app.M=app.M+mod(app.M,2);
                app.window = rectwin(app.M + 1); % 阶数
               
            elseif app.windowType==2
                app.M = ceil(6.1/abs(app.rw - app.lw)); % 点数
                app.M=app.M+mod(app.M,2);
                app.window = bartlett(app.M + 1); % 阶数 
                
            elseif app.windowType==3
                 app.M = ceil(6.2/abs(app.rw - app.lw)); % 点数
                 app.M=app.M+mod(app.M,2);
                app.window = hann(app.M + 1); % 阶数
               
            elseif app.windowType==4
                 app.M = ceil(6.6/abs(app.rw - app.lw)); % 点数
                 app.M=app.M+mod(app.M,2);
                app.window = hamming(app.M + 1); % 阶数
               
            elseif app.windowType == 5
                app.M = ceil(12/abs(app.rw - app.lw));
                app.M=app.M+mod(app.M,2);
                app.window = blackman(app.M + 1); % 阶数
             end            
             app.N = app.M+1; % 阶数
             % 阶数必须是奇数
             % Fix: M只能为实数
             %if mod(app.M,2) == 1
             %    app.M = app.M+1;
             %end
             
             
            if app.filterPassType==1
                app.b = fir1(app.M, app.wc , 'low',app.window);
            elseif app.filterPassType==2
                app.b = fir1(app.M, app.wc, 'high',app.window );
            elseif app.filterPassType==3
                app.b = fir1(app.M,[app.lw, app.rw], 'bandpass' ,app.window);
            elseif app.filterPassType==4
                app.b = fir1(app.M,[app.lw, app.rw], 'stop' ,app.window);
            end
                
            [h,w] = freqz(app.b,1);
            
            % 确定模拟滤波器的技术指标 ， 现在直接设计数字滤波器
            %app.Wp = 2./app.T.*tan(1/2*app.wp);
            %app.Ws = 2./app.T.*tan(1/2*app.ws);
            %app.Ap =app.ap;
            %app.As =app.as;
   
            % [h,w] = filterDesign(app);
            
            plot(app.filterSpectrumFig, w/pi,20*log10(abs(h)));
            plot(app.filterPhaseFig, w/pi,unwrap(angle(h)));
            
            
            app.freqResp = h;
            app.freqResp_w = w;
            
            
        end
    end

    methods (Access = private)


    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, otherApp)
            app.T = 2; % 设置T=2
            
            if nargin >=2
                app.callingApp=otherApp;
            end
            % showHighLowPanel(app);
            
            % 刚进来为矩形窗 低通
            app.windowType = 1;
            app.filterPassType=1;
            app.lw = app.leftOmegaEditField.Value;
            app.rw = app.rightOmegaEditField.Value;
            
            
            % resetPanel(app);
            
            app.filterTypeChangeCallback();
            app.filterPassTypeChangeCallback();
           
            
        end

        % Value changed function: leftOmegaEditField, 
        % rightOmegaEditField
        function filterUpdateCallback(app, event)

            updateFIRFilter(app);
        end

        % Value changed function: DropDown
        function filterTypeChangeCallback(app, event)
            value = app.DropDown.Value;
            
            
            if strcmp('矩形窗', value)
                app.windowType = 1;
            elseif strcmp('三角窗', value)
                app.windowType = 2;
            elseif strcmp('汉宁窗', value)
                app.windowType = 3;
            elseif strcmp('哈明窗', value)
                app.windowType = 4;
            elseif strcmp('布莱克曼窗', value)
                app.windowType = 5;
            end
            
            % resetPanel(app);
            updateFIRFilter(app);
        end

        % Value changed function: DropDown_2
        function filterPassTypeChangeCallback(app, event)
            value = app.DropDown_2.Value;
            if strcmp('低通',value)
                % showHighLowPanel(app);
                app.filterPassType=1;
                
                app.rightOmegaEditField.Value = 0.5;
                app.leftOmegaEditField.Value = 0.3;
                
            elseif strcmp('高通',value)
                % showHighLowPanel(app);
                app.filterPassType=2;
                                
                app.rightOmegaEditField.Value = 0.3;
                app.leftOmegaEditField.Value = 0.5;
            elseif strcmp('带通',value)
                
                % showBandPanel(app);
                app.filterPassType=3;
                
                app.rightOmegaEditField.Value = 0.5;
                app.leftOmegaEditField.Value = 0.3;
                
            elseif strcmp('带阻',value)
                
                % showBandPanel(app);
                app.filterPassType=4;
                
                app.rightOmegaEditField.Value = 0.5;
                app.leftOmegaEditField.Value = 0.3;
            end
            
            updateFIRFilter(app);
        end

        % Button pushed function: Button
        function filterApply(app, event)
            app.callingApp.filterSig = app.window;
            app.callingApp.setFilterDisign = 1;
            app.callingApp.Lamp_2.Color = 'g';
            app.callingApp.setFilterDisign = 1;
        end

        % Button pushed function: Button_2
        function FIRFilterConfirm(app, event)
               filterApply(app);
               delete(app);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.FIRUIFigure = uifigure;
            app.FIRUIFigure.Name = 'FIR滤波器设计';
            app.filterSpectrumFig = uiaxes(app.FIRUIFigure);
            title(app.filterSpectrumFig, '幅度响应')
            xlabel(app.filterSpectrumFig, '归一化角频率 ω (x\times \pi rads/sample)')
            ylabel(app.filterSpectrumFig, '幅频衰减(dB)')
            app.filterSpectrumFig.XLim = [0 1];
            app.left_Label = uilabel(app.FIRUIFigure);
            app.left_Label.Text = 'left_ω';
            app.leftOmegaEditField = uieditfield(app.FIRUIFigure, 'numeric');
            app.leftOmegaEditField.ValueChangedFcn = createCallbackFcn(app, @filterUpdateCallback, true);
            app.leftOmegaEditField.Value = 0.3;
            app.right_Label = uilabel(app.FIRUIFigure);
            app.right_Label.Text = 'right_ω';
            app.rightOmegaEditField = uieditfield(app.FIRUIFigure, 'numeric');
            app.rightOmegaEditField.ValueChangedFcn = createCallbackFcn(app, @filterUpdateCallback, true);
            app.rightOmegaEditField.Value = 0.5;
            app.FIRLabel = uilabel(app.FIRUIFigure);
            app.FIRLabel.Text = 'FIR 滤波器设计';
            app.Label = uilabel(app.FIRUIFigure);
            app.Label.Text = '窗口类型选择';
            app.DropDown = uidropdown(app.FIRUIFigure);
            app.DropDown.Items = {'矩形窗', '三角窗', '汉宁窗', '哈明窗', '布莱克曼窗'};
            app.DropDown.Editable = 'on';
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @filterTypeChangeCallback, true);
            app.DropDown.Value = '矩形窗';
            app.DropDown_2 = uidropdown(app.FIRUIFigure);
            app.DropDown_2.Items = {'低通', '高通', '带通', '带阻'};
            app.DropDown_2.ValueChangedFcn = createCallbackFcn(app, @filterPassTypeChangeCallback, true);
            app.DropDown_2.Value = '低通';
            app.filterPhaseFig = uiaxes(app.FIRUIFigure);
            title(app.filterPhaseFig, '相位')
            xlabel(app.filterPhaseFig, '归一化角频率 ω (x\times \pi rads/sample)')
            ylabel(app.filterPhaseFig, '相位 (rads)')
            app.filterPhaseFig.XLim = [0 1];
            app.Button = uibutton(app.FIRUIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @filterApply, true);
            app.Button.Text = '应用';
            app.Button_2 = uibutton(app.FIRUIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @FIRFilterConfirm, true);
            app.Button_2.Text = '确定';
        end
    end

    methods (Access = public)

        % Construct app
        function app = FIRfilterDesignApp(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.FIRUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.FIRUIFigure)
        end
    end
end