classdef IIRFilter < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        IIRUIFigure        matlab.ui.Figure
        outputSpectrumFig  matlab.ui.control.UIAxes
        outputPhaseFig     matlab.ui.control.UIAxes
        outputTimeFig1     matlab.ui.control.UIAxes
        outputTimeFig      matlab.ui.control.UIAxes
        Button             matlab.ui.control.Button
    end


    properties (Access = public)
        callingApp % 调用该App的app实例引用
        outputSig % Description
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainApp)
            app.callingApp = mainApp;
            
            app.outputSig = filter(app.callingApp.b,app.callingApp.a,app.callingApp.testSig);


            % 更新数据
            N = length(app.outputSig); % 原信号点数
            n  = linspace(0,N-1,N);
            fft_y = fft(app.outputSig); % 计算fft
            
            % n = linspace(0 , N-1,N); % 第n点
            w = 2*pi*n./N; % 角频率(未归一化)
            plot(app.outputTimeFig, app.outputSig);
            plot(app.outputSpectrumFig, w/pi,(abs(fft_y)));
            plot(app.outputPhaseFig, w/pi,angle(fft_y));
            
            
            mainApp.outputSig = app.outputSig;
        end

        % Button pushed function: Button
        function playOutputSig(app, event)
            sound(app.outputSig,44100);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.IIRUIFigure = uifigure;
            app.IIRUIFigure.Name = 'IIR 差分方程迭代滤波';
            app.outputSpectrumFig = uiaxes(app.IIRUIFigure);
            title(app.outputSpectrumFig, '幅度响应')
            xlabel(app.outputSpectrumFig, '归一化角频率 ω (x\times \pi rads/sample)')
            ylabel(app.outputSpectrumFig, '幅频衰减(dB)')
            app.outputSpectrumFig.XLim = [0 1];
            app.outputPhaseFig = uiaxes(app.IIRUIFigure);
            title(app.outputPhaseFig, '相位')
            xlabel(app.outputPhaseFig, '归一化角频率 ω (x\times \pi rads/sample)')
            ylabel(app.outputPhaseFig, '相位(rads)')
            app.outputPhaseFig.XLim = [0 1];
            app.outputTimeFig1 = uiaxes(app.IIRUIFigure);
            title(app.outputTimeFig1, '时域')
            xlabel(app.outputTimeFig1, 'n')
            ylabel(app.outputTimeFig1, '信号幅度')
            app.outputTimeFig1.XLim = [0 1];
            app.outputTimeFig = uiaxes(app.IIRUIFigure);
            title(app.outputTimeFig, 'Title')
            xlabel(app.outputTimeFig, 'X')
            ylabel(app.outputTimeFig, 'Y')
            app.Button = uibutton(app.IIRUIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @playOutputSig, true);
            app.Button.Text = '播放';
        end
    end

    methods (Access = public)

        % Construct app
        function app = IIRFilter(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.IIRUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.IIRUIFigure)
        end
    end
end