classdef IIRfilterDesignApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        IIRUIFigure               matlab.ui.Figure
        filterSpectrumFig         matlab.ui.control.UIAxes
        passEditFieldLabel        matlab.ui.control.Label
        passEditField             matlab.ui.control.NumericEditField
        pLabel                    matlab.ui.control.Label
        alphaPEditField           matlab.ui.control.NumericEditField
        stopEditFieldLabel        matlab.ui.control.Label
        stopEditField             matlab.ui.control.NumericEditField
        sLabel                    matlab.ui.control.Label
        alphasEditField           matlab.ui.control.NumericEditField
        IIRLabel                  matlab.ui.control.Label
        Label                     matlab.ui.control.Label
        DropDown                  matlab.ui.control.DropDown
        DropDown_2                matlab.ui.control.DropDown
        filterPhaseFig            matlab.ui.control.UIAxes
        stopupEditFieldLabel      matlab.ui.control.Label
        stopupEditField           matlab.ui.control.NumericEditField
        passupEditFieldLabel      matlab.ui.control.Label
        passupEditField           matlab.ui.control.NumericEditField
        stoplowEditFieldLabel     matlab.ui.control.Label
        stoplowEditField          matlab.ui.control.NumericEditField
        passlowEditFieldLabel     matlab.ui.control.Label
        passlowEditField          matlab.ui.control.NumericEditField
        passRippleEditFieldLabel  matlab.ui.control.Label
        passRippleEditField       matlab.ui.control.NumericEditField
        Button                    matlab.ui.control.Button
        Button_2                  matlab.ui.control.Button
    end


    properties (Access = public)
        T % T
        
        % 滤波器类型
        filterType  % 1: 巴特， 2: 切比雪夫 3: 椭圆
        filterPassType % 1: 低通 2:高通 3: 带通 4:带阻
        
        % 数字指标
        wp  % 通带截止角频率 0,1之间的标量或者两个标量的向量(带通)
        ap  % 通带最大衰减
        ws  % 阻带截止频率
        as  % 阻带最大衰减
        
        callingApp
        wup
        wlp
        wus
        wls
        
        a
        b
        
        rp % 切比雪夫峰峰值波浪(dB)
        
        % 模拟指标
        Wp  % 通带截止角频率
        Ap  % 通带最大衰减
        Ws  % 阻带截止频率
        As  % 阻带最大衰减
        
        % 频率响应与角频率
        freqResp % 频率响应
        freqResp_w % 角频率
        
    end

    methods (Access = public)
    
        function [] = updateIIRFilter(app)
            % 更新数字滤波器
            
            % 更新数字频率
            app.ws = app.stopEditField.Value;
            app.wp = app.passEditField.Value;
            app.wls = app.stoplowEditField.Value;
            app.wlp = app.passlowEditField.Value;
            app.wus = app.stopupEditField.Value;
            app.wup = app.passupEditField.Value;
            
            app.ap = app.alphaPEditField.Value;
            app.as = app.alphasEditField.Value;
            
            app.rp = app.passRippleEditField.Value;
            
            % 确定模拟滤波器的技术指标 ， 现在直接设计数字滤波器
            %app.Wp = 2./app.T.*tan(1/2*app.wp);
            %%app.Ws = 2./app.T.*tan(1/2*app.ws);
            %app.Ap =app.ap;
            %app.As =app.as;
   
            [h,w] = filterDesign(app);
            
            plot(app.filterSpectrumFig, w/pi,20*log10(abs(h)));
            plot(app.filterPhaseFig, w/pi,angle(h));
            
            app.freqResp = h;
            app.freqResp_w = w;
        end
        
        function [h,w] = butterFilterDesign(app)
            % 巴特沃兹滤波器设计
            

            if app.filterPassType==1
                [n,Wn] = buttord(app.wp,app.ws,app.ap,app.as);
                [app.b,app.a] = butter(n,Wn,'low');
            elseif app.filterPassType==2
                [n,Wn] = buttord(app.wp,app.ws,app.ap,app.as);
                [app.b,app.a] = butter(n,Wn,'high');
            elseif app.filterPassType==3
                [n,Wn] = buttord([app.wlp,app.wup],[app.wls,app.wus],app.ap,app.as);
                [app.b,app.a] = butter(n,Wn,'bandpass');
            elseif app.filterPassType==4
                [n,Wn] = buttord([app.wlp,app.wup],[app.wls,app.wus],app.ap,app.as);
                [app.b,app.a] = butter(n,Wn,'stop');
            end
             % 不带s 数字滤波器设计 返回H(z)[分子,分母]
            
            
            % 获得频率响应, 注意把w归一化！
            [h,w] = freqz(app.b,app.a); 
        end
    
            function [h,w] = chebz1FilterDesign(app)
            % 切比雪夫滤波器设计
            
            % 直接设计数字滤波器： 生成阶数
 
            if app.filterPassType==1
                [n,Wn] = cheb1ord(app.wp,app.ws,app.ap,app.as);
                [app.b,app.a] = cheby1(n,app.rp,Wn,'low');
            elseif app.filterPassType==2
                [n,Wn] = cheb1ord(app.wp,app.ws,app.ap,app.as);
                [app.b,app.a] = cheby1(n,app.rp,Wn,'high');
            elseif app.filterPassType==3
                [n,Wn] = cheb1ord([app.wlp,app.wup],[app.wls,app.wus],app.ap,app.as);
                [app.b,app.a] = cheby1(n,app.rp,Wn,'bandpass');
            elseif app.filterPassType==4
                [n,Wn] = cheb1ord([app.wlp,app.wup],[app.wls,app.wus],app.ap,app.as);
                [app.b,app.a] = cheby1(n,app.rp,Wn,'stop');
            end
            
             % 不带s 数字滤波器设计 返回H(z)[分子,分母]

            % 获得频率响应, 注意把w归一化！
            [h,w] = freqz(app.b,app.a); 
        end
        
        function [h,w]= filterDesign(app)
            
            h = 0;
            w = 0;
            
            if app.filterType == 1
             [h,w] = butterFilterDesign(app);   
            elseif app.filterType == 2
             [h,w] = chebz1FilterDesign(app);
            end
             
             
        end
        
        function results = resetPanel(app)
            if app.filterType == 1
                app.passRippleEditField.Visible = 'off';
                app.passRippleEditFieldLabel.Visible = 'off';
            elseif app.filterType == 2
                app.passRippleEditField.Visible = 'on';
                app.passRippleEditFieldLabel.Visible = 'on';
            end  
        end


    end

    methods (Access = private)
    
        function results = showHighLowPanel(app)
            % 显示低通, 高通模块
            
            app.passEditField.Visible = 'on';
            app.stopEditField.Visible = 'on';
            app.passEditFieldLabel.Visible = 'on';
            app.stopEditFieldLabel.Visible = 'on';
            
            app.passupEditField.Visible = 'off';
            app.passlowEditField.Visible='off';
            app.stoplowEditField.Visible ='off';
            app.stopupEditField.Visible = 'off';
            
            app.passupEditFieldLabel.Visible = 'off';
            app.passlowEditFieldLabel.Visible='off';
            app.stoplowEditFieldLabel.Visible ='off';
            app.stopupEditFieldLabel.Visible = 'off';
        end
    
    
        function results = showBandPanel(app)
            % 显示带通,带阻模块
            
            app.passEditField.Visible = 'off';
            app.stopEditField.Visible = 'off';
            app.passEditFieldLabel.Visible = 'off';
            app.stopEditFieldLabel.Visible = 'off';
            
            app.passupEditField.Visible = 'on';
            app.passlowEditField.Visible='on';
            app.stoplowEditField.Visible ='on';
            app.stopupEditField.Visible = 'on';
            
            app.passupEditFieldLabel.Visible = 'on';
            app.passlowEditFieldLabel.Visible='on';
            app.stoplowEditFieldLabel.Visible ='on';
            app.stopupEditFieldLabel.Visible = 'on';


        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainApp)
            app.T = 2; % 设置T=2
            
            showHighLowPanel(app);
            
            % 刚进来为巴特沃兹低通
            app.filterType = 1;
            app.filterPassType=1;
            
            resetPanel(app);
            
            app.callingApp = mainApp;
            
            app.filterTypeChangeCallback();
            app.filterPassTypeChangeCallback();
           
            
        end

        % Value changed function: alphaPEditField, alphasEditField, 
        % passEditField, passRippleEditField, passlowEditField, 
        % passupEditField, stopEditField, stoplowEditField, 
        % stopupEditField
        function filterUpdateCallback(app, event)

            updateIIRFilter(app);
        end

        % Value changed function: DropDown
        function filterTypeChangeCallback(app, event)
            value = app.DropDown.Value;
            if strcmp('巴特沃兹滤波器',value)
                app.filterType = 1;
            elseif strcmp('切比雪夫Ⅰ型滤波器',value)
                app.filterType = 2;
            end
            resetPanel(app);
            updateIIRFilter(app);
        end

        % Value changed function: DropDown_2
        function filterPassTypeChangeCallback(app, event)
            value = app.DropDown_2.Value;
            if strcmp('低通',value)
                showHighLowPanel(app);
                app.filterPassType=1;
                
                app.stopEditField.Value = 0.5;
                app.passEditField.Value = 0.3;
                
            elseif strcmp('高通',value)
                showHighLowPanel(app);
                app.filterPassType=2;
                                
                app.stopEditField.Value = 0.3;
                app.passEditField.Value = 0.5;
            elseif strcmp('带通',value)
                
                showBandPanel(app);
                app.filterPassType=3;
                
                
                app.stoplowEditField.Value = 0.1;
                app.stopupEditField.Value = 0.7;
                app.passlowEditField.Value = 0.3;
                app.passupEditField.Value = 0.5;
                
                
            elseif strcmp('带阻',value)
                
                showBandPanel(app);
                app.filterPassType=4;
                
                app.stoplowEditField.Value = 0.3;
                app.stopupEditField.Value = 0.5;
                app.passlowEditField.Value = 0.1;
                app.passupEditField.Value = 0.7;
            end
            
            updateIIRFilter(app);
        end

        % Button pushed function: Button
        function filterApply(app, event)
            app.callingApp.Lamp_2.Color = 'g';
            app.callingApp.setFilterDisign = 1;
            
            app.callingApp.b = app.b;
            app.callingApp.a = app.a;
            
        end

        % Button pushed function: Button_2
        function filterConfirm(app, event)
            filterApply(app);
            delete(app);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.IIRUIFigure = uifigure;
            app.IIRUIFigure.Name = 'IIR滤波器设计';
            app.filterSpectrumFig = uiaxes(app.IIRUIFigure);
            title(app.filterSpectrumFig, '幅度响应')
            xlabel(app.filterSpectrumFig, '归一化角频率 ω (x\times \pi rads/sample)')
            ylabel(app.filterSpectrumFig, '幅频衰减(dB)')
            app.filterSpectrumFig.XLim = [0 1];
            app.passEditFieldLabel = uilabel(app.IIRUIFigure);
            app.passEditFieldLabel.Text = 'ωp';
            app.passEditField = uieditfield(app.IIRUIFigure, 'numeric');
            app.passEditField.ValueChangedFcn = createCallbackFcn(app, @filterUpdateCallback, true);
            app.passEditField.Value = 0.3;
            app.pLabel = uilabel(app.IIRUIFigure);
            app.pLabel.Text = 'αp';
            app.alphaPEditField = uieditfield(app.IIRUIFigure, 'numeric');
            app.alphaPEditField.ValueChangedFcn = createCallbackFcn(app, @filterUpdateCallback, true);
            app.alphaPEditField.Value = 2;
            app.stopEditFieldLabel = uilabel(app.IIRUIFigure);
            app.stopEditFieldLabel.Text = 'ωs';
            app.stopEditField = uieditfield(app.IIRUIFigure, 'numeric');
            app.stopEditField.ValueChangedFcn = createCallbackFcn(app, @filterUpdateCallback, true);
            app.stopEditField.Value = 0.5;
            app.sLabel = uilabel(app.IIRUIFigure);
            app.sLabel.Text = 'αs';
            app.alphasEditField = uieditfield(app.IIRUIFigure, 'numeric');
            app.alphasEditField.ValueChangedFcn = createCallbackFcn(app, @filterUpdateCallback, true);
            app.alphasEditField.Value = 15;
            app.IIRLabel = uilabel(app.IIRUIFigure);
            app.IIRLabel.Text = 'IIR 滤波器设计';
            app.Label = uilabel(app.IIRUIFigure);
            app.Label.Text = '滤波器类型选择';
            app.DropDown = uidropdown(app.IIRUIFigure);
            app.DropDown.Items = {'巴特沃兹滤波器', '切比雪夫Ⅰ型滤波器', '椭圆滤波器'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @filterTypeChangeCallback, true);
            app.DropDown.Value = '巴特沃兹滤波器';
            app.DropDown_2 = uidropdown(app.IIRUIFigure);
            app.DropDown_2.Items = {'低通', '高通', '带通', '带阻'};
            app.DropDown_2.ValueChangedFcn = createCallbackFcn(app, @filterPassTypeChangeCallback, true);
            app.DropDown_2.Value = '低通';
            app.filterPhaseFig = uiaxes(app.IIRUIFigure);
            title(app.filterPhaseFig, '相位')
            xlabel(app.filterPhaseFig, '归一化角频率 ω (x\times \pi rads/sample)')
            ylabel(app.filterPhaseFig, '幅频衰减(dB)')
            app.filterPhaseFig.XLim = [0 1];
            app.stopupEditFieldLabel = uilabel(app.IIRUIFigure);
            app.stopupEditFieldLabel.Text = 'ωus';
            app.stopupEditField = uieditfield(app.IIRUIFigure, 'numeric');
            app.stopupEditField.ValueChangedFcn = createCallbackFcn(app, @filterUpdateCallback, true);
            app.stopupEditField.Value = 0.8;
            app.passupEditFieldLabel = uilabel(app.IIRUIFigure);
            app.passupEditFieldLabel.Text = 'ωup';
            app.passupEditField = uieditfield(app.IIRUIFigure, 'numeric');
            app.passupEditField.ValueChangedFcn = createCallbackFcn(app, @filterUpdateCallback, true);
            app.passupEditField.Value = 0.1;
            app.stoplowEditFieldLabel = uilabel(app.IIRUIFigure);
            app.stoplowEditFieldLabel.Text = 'ωls';
            app.stoplowEditField = uieditfield(app.IIRUIFigure, 'numeric');
            app.stoplowEditField.ValueChangedFcn = createCallbackFcn(app, @filterUpdateCallback, true);
            app.stoplowEditField.Value = 0.5;
            app.passlowEditFieldLabel = uilabel(app.IIRUIFigure);
            app.passlowEditFieldLabel.Text = 'ωlp';
            app.passlowEditField = uieditfield(app.IIRUIFigure, 'numeric');
            app.passlowEditField.ValueChangedFcn = createCallbackFcn(app, @filterUpdateCallback, true);
            app.passlowEditField.Value = 0.3;
            app.passRippleEditFieldLabel = uilabel(app.IIRUIFigure);
            app.passRippleEditFieldLabel.Text = 'Rp';
            app.passRippleEditField = uieditfield(app.IIRUIFigure, 'numeric');
            app.passRippleEditField.ValueChangedFcn = createCallbackFcn(app, @filterUpdateCallback, true);
            app.passRippleEditField.Value = 2;
            app.Button = uibutton(app.IIRUIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @filterApply, true);
            app.Button.Text = '应用';
            app.Button_2 = uibutton(app.IIRUIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @filterConfirm, true);
            app.Button_2.Text = '确定';
        end
    end

    methods (Access = public)

        % Construct app
        function app = IIRfilterDesignApp(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.IIRUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.IIRUIFigure)
        end
    end
end