classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        UIAxes               matlab.ui.control.UIAxes
        readAudioFilebtn     matlab.ui.control.Button
        sigGenPanelBtn       matlab.ui.control.Button
        Label                matlab.ui.control.Label
        Label_2              matlab.ui.control.Label
        Button_7             matlab.ui.control.Button
        Button_8             matlab.ui.control.Button
        Label_3              matlab.ui.control.Label
        recordBtn            matlab.ui.control.StateButton
        originSIgSpectrum    matlab.ui.control.UIAxes
        originPlayBtn        matlab.ui.control.Button
        originSIgPhaseFig    matlab.ui.control.UIAxes
        noiseUIAxes          matlab.ui.control.UIAxes
        originSIgSpectrum_2  matlab.ui.control.UIAxes
        noisePlayBtn         matlab.ui.control.Button
        originSIgPhaseFig_3  matlab.ui.control.UIAxes
        originSIgSpectrum_3  matlab.ui.control.UIAxes
        UIAxes_4             matlab.ui.control.UIAxes
        originPlayBtn_3      matlab.ui.control.Button
        originPlayBtn_4      matlab.ui.control.Button
        recordBtn_2          matlab.ui.control.StateButton
        ButtonGroup          matlab.ui.container.ButtonGroup
        multiSineTypeBtn     matlab.ui.control.RadioButton
        digitalAudioTypeBtn  matlab.ui.control.RadioButton
        noiseGenBtn          matlab.ui.control.Button
    end


    properties (Access = private)
        audioRecorderObj  % 音频录制对象, 用来录制,保存音频数据
    end

    properties (Access = public)
        originSig % 原信号 vector : 用来保存音频数据 
        
        sigType % 信号类型  1: 多频正弦 2: 数字音频
        
        noiseSig % 噪音信号 vector 保存噪音数据
        
        isSetOrigin % 原信号是否设置
        isSetNoise % 信号噪音是否设置
        isSetFilter % 滤波器是否设置
    end

    methods (Access = public)
    
        function results = updateOrigin(app)
            % 更新原信号模块
            
            app.isSetOrigin = 1; % 设置已经设置
            
            % 绘制原信号音频--时域图
            plot(app.UIAxes,app.originSig);
            
            % 更新数据
            N = length(app.originSig); % 原信号点数
            fft_y = fft(app.originSig); % 计算fft
            
            n = linspace(0 , N-1,N); % 第n点
            w = 2*pi*n./N; % 角频率(未归一化)
            
            % 绘制原信号音频--频谱图
            plot(app.originSIgSpectrum,w/pi,abs(fft_y));  % w归一化
            
            % 绘制原信号音频--相频图
            plot(app.originSIgPhaseFig,w/pi,angle(fft_y));
            
        end
        
        function results = redrawPanel(app)
            % 重新绘制Panel数据
            
            
        end
    
        function results = updateNoise(app)
            % 更新噪音信号模块
            
            % 代表已经设置噪音信号
            app.isSetNoise = 1; 
            
            % 绘制噪音信号音频--时域图
            plot(app.noiseUIAxes,app.noiseSig);
            
            % 更新数据
            N = length(app.noiseSig); % 噪音信号点数
            fft_y = fft(app.noiseSig); % 计算fft
            
            n = linspace(0 , N-1,N); % 第n点
            w = 2*pi*n./N; % 角频率(未归一化)
            
            % 绘制噪音信号--频谱图
            plot(app.originSIgSpectrum_2,w/pi,abs(fft_y));  % w归一化
            
        end


        function [] = updateSigPanel(app, panelType)
            % 更新面板显示
            if panelType==1
                app.readAudioFilebtn.Visible = 'off';
                app.recordBtn.Visible='off';
                app.sigGenPanelBtn.Visible='on';
                
                app.noiseGenBtn.Visible = 'off'; % 不显示噪音模块
                
            elseif panelType==2
                app.readAudioFilebtn.Visible = 'on';
                app.recordBtn.Visible='on';
                app.sigGenPanelBtn.Visible='off';
                
                if app.sigType ==2 
                    app.noiseGenBtn.Visible='on';
                end


            end

            
        end
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            % 隐藏正弦函数叠加模块
            %set(app.SineSigGeneratePanel,'visible','off');
            % 初始化音频录制对象
            app.audioRecorderObj = audiorecorder; 
            
            app.isSetOrigin = 0;
            app.isSetNoise = 0;
            app.isSetFilter = 0;
            app.sigType=1; % 默认为 1:多频正弦
            
            updateSigPanel(app,1);
            
        end

        % Button pushed function: readAudioFilebtn
        function readAudioFilebtnPushed(app, event)
            
            % 打开文件选择框
            [file,path] = uigetfile({'*.wav';'*.ogg';'*.flac';'*.au';'*.aiff';'*.aif';'*.aifc';'*.mp3';'*.m4a';'*.mp4';'*.*'},...
                          'Surpported Audio File');  
            
            % 如果选择了文件那么打开音频文件
            if path ~= 0
                % 获得文件地址
                audio_file_path = [path,file]; 
                % 打开音频文件
                [app.originSig,Fs] = audioread(audio_file_path);
                
                app.sigType = 2; % 数字音频
                updateOrigin(app); % 更新原信号
                updateSigPanel(app,2); % 显示噪音模块
            end
        end

        % Value changed function: recordBtn
        function recordBtnValueChanged(app, event)
            value = app.recordBtn.Value;
           
            % 按下录制按钮
            if value== 1
                record(app.audioRecorderObj); % 开始录制
            % 结束录制
            else
                if ~isempty(app.audioRecorderObj)
                    app.originSig = getaudiodata(app.audioRecorderObj); % 获取录制数据
                    stop(app.audioRecorderObj); % 停止录制(貌似会销毁audioRecorder对象)
                    app.audioRecorderObj = audiorecorder; % 重新赋值避免空异常
                    app.sigType = 2; % 数字音频
                    updateOrigin(app); % 更新
                    updateSigPanel(app,2); % 显示噪音模块
                end
            end          
        end

        % Button pushed function: originPlayBtn
        function originPlayBtnPushed(app, event)
            % 播放原信号(生成的信号)
            sound(app.originSig) 
        end

        % Button pushed function: sigGenPanelBtn
        function FreeGenerateSignal(app, event)
            
            
            app2(app);  % 打开正弦信号生成模块


        end

        % Callback function
        function closePanelBtnButtonPushed(app, event)
            set(app.SineSigGeneratePanel,'visible','off');
        end

        % Callback function
        function singQuitButtonPushed(app, event)
            % 退出控制面板
            set(app.SineSigGeneratePanel,'visible','off');
        end

        % Callback function
        function sineConfirmButtonPushed(app, event)
            set(app.SineSigGeneratePanel,'visible','on');
        end

        % Button pushed function: Button_7
        function noiseFileRead(app, event)
                        
            % 打开文件选择框
            [file,path] = uigetfile({'*.wav';'*.ogg';'*.flac';'*.au';'*.aiff';'*.aif';'*.aifc';'*.mp3';'*.m4a';'*.mp4';'*.*'},...
                          'Surpported Audio File');  
            
            % 如果选择了文件那么打开音频文件
            if path ~= 0
                % 获得文件地址
                audio_file_path = [path,file]; 
                % 打开音频文件
                [app.noiseSig,Fs] = audioread(audio_file_path);
                updateNoise(app); % 更新噪音
            end
        end

        % Button pushed function: noisePlayBtn
        function noisePlayBtnCallback(app, event)
            sound(app.noiseSig);
        end

        % Button pushed function: Button_8
        function noiseGenerateCallback(app, event)
            noiseGenApp(app);
        end

        % Selection changed function: ButtonGroup
        function sigTypeChangeCallback(app, event)
            if app.multiSineTypeBtn == app.ButtonGroup.SelectedObject
                panelType= 1; % 多频正弦
            elseif app.digitalAudioTypeBtn == app.ButtonGroup.SelectedObject
                panelType= 2; % 数字音频
            end
            updateSigPanel(app,panelType);
        end

        % Button pushed function: noiseGenBtn
        function noiseGenBtnCallback(app, event)
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '时域图')
            xlabel(app.UIAxes, 'n')
            ylabel(app.UIAxes, '信号幅值')
            app.readAudioFilebtn = uibutton(app.UIFigure, 'push');
            app.readAudioFilebtn.ButtonPushedFcn = createCallbackFcn(app, @readAudioFilebtnPushed, true);
            app.readAudioFilebtn.Text = '从文件中读取';
            app.sigGenPanelBtn = uibutton(app.UIFigure, 'push');
            app.sigGenPanelBtn.ButtonPushedFcn = createCallbackFcn(app, @FreeGenerateSignal, true);
            app.sigGenPanelBtn.Text = '自由生成';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '信号生成';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '信号接收';
            app.Button_7 = uibutton(app.UIFigure, 'push');
            app.Button_7.ButtonPushedFcn = createCallbackFcn(app, @noiseFileRead, true);
            app.Button_7.Text = '从文件中读取';
            app.Button_8 = uibutton(app.UIFigure, 'push');
            app.Button_8.ButtonPushedFcn = createCallbackFcn(app, @noiseGenerateCallback, true);
            app.Button_8.Text = '自由生成';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '噪音生成';
            app.recordBtn = uibutton(app.UIFigure, 'state');
            app.recordBtn.ValueChangedFcn = createCallbackFcn(app, @recordBtnValueChanged, true);
            app.recordBtn.Text = '语音输入';
            app.originSIgSpectrum = uiaxes(app.UIFigure);
            title(app.originSIgSpectrum, '幅频图')
            xlabel(app.originSIgSpectrum, 'w/\pi')
            ylabel(app.originSIgSpectrum, '信号幅值')
            app.originPlayBtn = uibutton(app.UIFigure, 'push');
            app.originPlayBtn.ButtonPushedFcn = createCallbackFcn(app, @originPlayBtnPushed, true);
            app.originPlayBtn.Text = '播放';
            app.originSIgPhaseFig = uiaxes(app.UIFigure);
            title(app.originSIgPhaseFig, '相频图')
            xlabel(app.originSIgPhaseFig, 'w/\pi')
            ylabel(app.originSIgPhaseFig, '信号相位')
            app.noiseUIAxes = uiaxes(app.UIFigure);
            title(app.noiseUIAxes, '时域图')
            xlabel(app.noiseUIAxes, 'n')
            ylabel(app.noiseUIAxes, '信号幅值')
            app.originSIgSpectrum_2 = uiaxes(app.UIFigure);
            title(app.originSIgSpectrum_2, '幅频图')
            xlabel(app.originSIgSpectrum_2, 'w/\pi')
            ylabel(app.originSIgSpectrum_2, '信号幅值')
            app.noisePlayBtn = uibutton(app.UIFigure, 'push');
            app.noisePlayBtn.ButtonPushedFcn = createCallbackFcn(app, @noisePlayBtnCallback, true);
            app.noisePlayBtn.Text = '播放';
            app.originSIgPhaseFig_3 = uiaxes(app.UIFigure);
            title(app.originSIgPhaseFig_3, '相频图')
            xlabel(app.originSIgPhaseFig_3, 'w/\pi')
            ylabel(app.originSIgPhaseFig_3, '信号相位')
            app.originSIgSpectrum_3 = uiaxes(app.UIFigure);
            title(app.originSIgSpectrum_3, '幅频图')
            xlabel(app.originSIgSpectrum_3, 'w/\pi')
            ylabel(app.originSIgSpectrum_3, '信号幅值')
            app.UIAxes_4 = uiaxes(app.UIFigure);
            title(app.UIAxes_4, '时域图')
            xlabel(app.UIAxes_4, 'n')
            ylabel(app.UIAxes_4, '信号幅值')
            app.originPlayBtn_3 = uibutton(app.UIFigure, 'push');
            app.originPlayBtn_3.Text = '播放';
            app.originPlayBtn_4 = uibutton(app.UIFigure, 'push');
            app.originPlayBtn_4.Text = '滤波';
            app.recordBtn_2 = uibutton(app.UIFigure, 'state');
            app.recordBtn_2.Text = '显示/关闭原信号';
            app.ButtonGroup = uibuttongroup(app.UIFigure);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @sigTypeChangeCallback, true);
            app.ButtonGroup.Title = '信号类型选择';
            app.multiSineTypeBtn = uiradiobutton(app.ButtonGroup);
            app.multiSineTypeBtn.Text = '多频正弦';
            app.multiSineTypeBtn.Value = true;
            app.digitalAudioTypeBtn = uiradiobutton(app.ButtonGroup);
            app.digitalAudioTypeBtn.Text = '数字音频';
            app.noiseGenBtn = uibutton(app.UIFigure, 'push');
            app.noiseGenBtn.ButtonPushedFcn = createCallbackFcn(app, @noiseGenBtnCallback, true);
            app.noiseGenBtn.Text = '噪音生成';
        end
    end

    methods (Access = public)

        % Construct app
        function app = app1

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end