classdef app2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        sin2piftLabel     matlab.ui.control.Label
        sineConfirm       matlab.ui.control.Button
        singQuit          matlab.ui.control.Button
        sinePosSpinner    matlab.ui.control.Spinner
        HzLabel           matlab.ui.control.Label
        HzLabel_2         matlab.ui.control.Label
        Label             matlab.ui.control.Label
        Label_2           matlab.ui.control.Label
        Label_3           matlab.ui.control.Label
        sineSampleFreq    matlab.ui.control.NumericEditField
        EditFieldLabel    matlab.ui.control.Label
        sampleNum         matlab.ui.control.NumericEditField
        Label_4           matlab.ui.control.Label
        sineNum           matlab.ui.control.Spinner
        EditField_2Label  matlab.ui.control.Label
        sinePosFreq       matlab.ui.control.NumericEditField
        sineGenFig        matlab.ui.control.UIAxes
    end


    properties (Access = public)
        % 调用该窗口的窗口实例
        callingApp  
        % sine信号
        sineSig = 0 
        
        % 正弦信号个数
        sn 
        
        % 频率列表 1..sn
        f 
        
        % 采样点数
        N
        
        % 指定的正弦函数
        sinFunc = @(f, t) 100*sin(2*pi*f*t); %正弦函数
        
        % 采样频率
        fs
        
        % 默认频率 1kHz
        SINE_FREQ = 1000 
 
    end

    methods (Access = public)
    
        function [] = updateCurrentFreq(app)
            % 获取当前设置的位置
            pos = app.sinePosSpinner.value;
            
            % 获取当前设置的频率值
            freq = app.sinePosFeq;
            
            %  设置函数
           % app.sinFunc = @(f, t) 100*sin(2*pi*f*t); %正弦函数
            
            % 设置
            app.f(pos) = freq;
            
            % 绘图
            %draw(app);
        end
        
        function [] = draw(app)
        % ====根据属性数据绘制图形====
               
            % 初始化正弦信号值 
            app.sineSig=zeros(app.N,1); % 初始化正弦数据列表
            
            % 计算采样时间 
            np = app.N; % 采样点数
            n = linspace(0 , np-1,np);  % vector 采样向量 [0..N-1]
            sample_t = n' / app.fs; %  Nx1 vector 采样时间位置 = 采样点/采样频率


            % 获得正弦信号
            
            % 遍历采样共sn个正弦信号
            for i=1:app.sn
                sin_i = app.sinFunc(app.f(i) , sample_t); % Nx1 f(i)为第i个的频率, sample_t为采样时间点 
                app.sineSig =app.sineSig +sin_i;   % Nx1
            end
            
            %  绘制采样前后的图
             stem(app.sineGenFig, sample_t, app.sineSig); % 绘制采样后的图
        end
        
    end

    methods (Access = private)
    
        function results = reset(app, which)
            % 控制面板重置
            
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function starupFcn(app, mainapp)
            app.callingApp = mainapp;
            
            app.sn = app.sineNum.Value; % 正弦信号个数
            app.fs= app.sineSampleFreq.Value; % 更新采样频率
            app.f(1) = app.sinePosFreq; % 频率设置 1kHz
            app.N= app.sampleNum.Value; % 更新采样点数
            
            % 初始绘制一个图
            draw(app);


        end

        % Callback function
        function openApp(app, event)


        end

        % Button pushed function: sineConfirm
        function sineConfirmCallback(app, event)
            % 确认了选择的部分
            
            app1.originSig


        end

        % Value changed function: sineNum
        function sineNumChangeCallback(app, event)
            % 正弦信号数量
            app.sn = app.sineNum.Value;  % scalar 
            
            % 更新当前正弦信号频率
            updateCurrentFreq(app);
            
            % 初始化频率列表 (利用默认频率1kHz)
            app.f() = ones(app.N,1) .* app.SINE_FREQ;
            
            % 绘制图形
            draw(app);
            
        end

        % Value changed function: sineSampleFreq
        function freqUpdateCallback(app, event)
            app.fs= app.sineSampleFreq.Value; % 更新采样频率
            
            % 重新绘制图形
            draw(app);
            
        end

        % Value changed function: sampleNum
        function sampleNumValueChanged(app, event)
            
            % 更新采样点数
            app.N= app.sampleNum.Value;
            
            % 重新绘制
            draw(app);


        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = '正弦信号生成系统';
            app.sin2piftLabel = uilabel(app.UIFigure);
            app.sin2piftLabel.Text = '100 sin(2\pi ft)';
            app.sineConfirm = uibutton(app.UIFigure, 'push');
            app.sineConfirm.ButtonPushedFcn = createCallbackFcn(app, @sineConfirmCallback, true);
            app.sineConfirm.Text = '确认';
            app.singQuit = uibutton(app.UIFigure, 'push');
            app.singQuit.Text = '退出';
            app.sinePosSpinner = uispinner(app.UIFigure);
            app.sinePosSpinner.Limits = [1 Inf];
            app.sinePosSpinner.Value = 1;
            app.HzLabel = uilabel(app.UIFigure);
            app.HzLabel.Text = 'Hz';
            app.HzLabel_2 = uilabel(app.UIFigure);
            app.HzLabel_2.Text = 'Hz';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '点';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '个';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '采样频率';
            app.sineSampleFreq = uieditfield(app.UIFigure, 'numeric');
            app.sineSampleFreq.Limits = [1 Inf];
            app.sineSampleFreq.ValueDisplayFormat = '%.1f';
            app.sineSampleFreq.ValueChangedFcn = createCallbackFcn(app, @freqUpdateCallback, true);
            app.sineSampleFreq.Value = 2000;
            app.EditFieldLabel = uilabel(app.UIFigure);
            app.EditFieldLabel.Text = '采样点数';
            app.sampleNum = uieditfield(app.UIFigure, 'numeric');
            app.sampleNum.Limits = [1 Inf];
            app.sampleNum.ValueDisplayFormat = '%.0f';
            app.sampleNum.ValueChangedFcn = createCallbackFcn(app, @sampleNumValueChanged, true);
            app.sampleNum.Value = 200;
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = '正弦信号数量';
            app.sineNum = uispinner(app.UIFigure);
            app.sineNum.Limits = [1 Inf];
            app.sineNum.ValueChangedFcn = createCallbackFcn(app, @sineNumChangeCallback, true);
            app.sineNum.Value = 1;
            app.EditField_2Label = uilabel(app.UIFigure);
            app.EditField_2Label.Text = '第                   个正弦信号频率设定';
            app.sinePosFreq = uieditfield(app.UIFigure, 'numeric');
            app.sinePosFreq.Limits = [0 Inf];
            app.sinePosFreq.Value = 1000;
            app.sineGenFig = uiaxes(app.UIFigure);
            title(app.sineGenFig, 'Title')
            xlabel(app.sineGenFig, 'X')
            ylabel(app.sineGenFig, 'Y')
        end
    end

    methods (Access = public)

        % Construct app
        function app = app2(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)starupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end