classdef mainApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure     matlab.ui.Figure
        Label        matlab.ui.control.Label
        Label_2      matlab.ui.control.Label
        Lamp         matlab.ui.control.Lamp
        Lamp_2Label  matlab.ui.control.Label
        Lamp_2       matlab.ui.control.Lamp
        Lamp_3Label  matlab.ui.control.Label
        Lamp_3       matlab.ui.control.Lamp
        Lamp_4Label  matlab.ui.control.Label
        Lamp_4       matlab.ui.control.Lamp
        Button       matlab.ui.control.Button
        Button_2     matlab.ui.control.Button
        Button_3     matlab.ui.control.Button
        ButtonGroup  matlab.ui.container.ButtonGroup
        IIRButton    matlab.ui.control.RadioButton
        FIRButton    matlab.ui.control.RadioButton
        Button_4     matlab.ui.control.Button
    end


    properties (Access = public)
        % 原信号, 不包括噪音
        originSig 
        
        % 测试信号, 包括噪音
        testSig
        
        % 噪音信号
        noiseSig
        
        % 输出信号
        outputSig
        
        % 信号类型
        sigType % 1: 多频正弦 2: 数字音频
         
        % 滤波器类型
        filterType % 1: IIR 滤波器 2: FIR滤波器
        
        % FIR滤波器时域
        filterSig
        
        % IIR滤波器H(z)分子与分母
        b % 分子
        a % 分母
        
        % 当前状态
        setTestSig % 已经设置测试信号
        setFilterDisign % 已经进行滤波器设计
        setDigitalFilter % 已经进行数字滤波
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.filterType = 1;
        end

        % Button pushed function: Button
        function testSIgGenModBtn(app, event)
            app1(app); % 打开测试信号生成模块
        end

        % Button pushed function: Button_2
        function openFilterDesinApp(app, event)
            
            if app.filterType == 1
                IIRfilterDesignApp(app);
            elseif app.filterType == 2
                FIRfilterDesignApp(app);
            end
            
        end

        % Selection changed function: ButtonGroup
        function filterTypeChangeCallback(app, event)
            selectedButton = app.ButtonGroup.SelectedObject;
            if app.IIRButton == selectedButton
                app.filterType = 1;
            elseif app.FIRButton == selectedButton
                app.filterType = 2;
            end
        end

        % Button pushed function: Button_3
        function digitalFilterApp(app, event)
            if app.filterType == 1
                % IIR 滤波器类型
                IIRFilter(app);
            elseif app.filterType == 2
                % FIR 滤波器类型
                FIRFilter(app);
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = '数字信号处理演示系统';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '数字信号处理演示系统';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '生成测试信号';
            app.Lamp = uilamp(app.UIFigure);
            app.Lamp_2Label = uilabel(app.UIFigure);
            app.Lamp_2Label.Text = '滤波器设计';
            app.Lamp_2 = uilamp(app.UIFigure);
            app.Lamp_3Label = uilabel(app.UIFigure);
            app.Lamp_3Label.Text = '数字滤波';
            app.Lamp_3 = uilamp(app.UIFigure);
            app.Lamp_4Label = uilabel(app.UIFigure);
            app.Lamp_4Label.Text = '输出信号分析';
            app.Lamp_4 = uilamp(app.UIFigure);
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @testSIgGenModBtn, true);
            app.Button.Text = '测试信号生成模块';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @openFilterDesinApp, true);
            app.Button_2.Text = '滤波器设计模块';
            app.Button_3 = uibutton(app.UIFigure, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @digitalFilterApp, true);
            app.Button_3.Text = '数字滤波模块';
            app.ButtonGroup = uibuttongroup(app.UIFigure);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @filterTypeChangeCallback, true);
            app.IIRButton = uiradiobutton(app.ButtonGroup);
            app.IIRButton.Text = 'IIR';
            app.IIRButton.Value = true;
            app.FIRButton = uiradiobutton(app.ButtonGroup);
            app.FIRButton.Text = 'FIR';
            app.Button_4 = uibutton(app.UIFigure, 'push');
            app.Button_4.Text = '输出信号分析模块';
        end
    end

    methods (Access = public)

        % Construct app
        function app = mainApp

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end