classdef testSigGenApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        UIAxes               matlab.ui.control.UIAxes
        readAudioFilebtn     matlab.ui.control.Button
        sigGenPanelBtn       matlab.ui.control.Button
        Label                matlab.ui.control.Label
        Label_3              matlab.ui.control.Label
        recordBtn            matlab.ui.control.StateButton
        originSIgSpectrum    matlab.ui.control.UIAxes
        originPlayBtn        matlab.ui.control.Button
        originSIgPhaseFig    matlab.ui.control.UIAxes
        testSigUIAxes        matlab.ui.control.UIAxes
        testSigSpectrum      matlab.ui.control.UIAxes
        testPlayBtn          matlab.ui.control.Button
        ButtonGroup          matlab.ui.container.ButtonGroup
        multiSineTypeBtn     matlab.ui.control.RadioButton
        digitalAudioTypeBtn  matlab.ui.control.RadioButton
        noiseGenBtn          matlab.ui.control.Button
        Button_9             matlab.ui.control.Button
        Button_10            matlab.ui.control.Button
        testSIgPhaseFig      matlab.ui.control.UIAxes
    end


    properties (Access = private)
        audioRecorderObj  % 音频录制对象, 用来录制,保存音频数据
    end

    properties (Access = public)
        originSig % 原信号 vector : 用来保存原信号数据 
        
        testSig % 最终测试信号 vector : 用来保存测试信号数据 
        
        sigType % 信号类型  1: 多频正弦 2: 数字音频
        
        noiseSig % 噪音信号 vector 保存噪音数据
        
        % callingApp 调用的app mainApp
        callingApp 
        
        isSetOrigin % 原信号是否设置
        isSetNoise % 信号噪音是否设置
        isSetFilter % 滤波器是否设置
    end

    methods (Access = public)
    
        function results = updateOrigin(app)
            % 更新原信号模块
            
            % app.noiseSig = zeros(size(app.originSig));
            
            app.isSetOrigin = 1; % 设置已经设置
            
            % 绘制原信号音频--时域图
            plot(app.UIAxes,app.originSig);
            
            % 更新数据
            N = length(app.originSig); % 原信号点数
            fft_y = fft(app.originSig); % 计算fft
            
            n = linspace(0 , N-1,N); % 第n点
            w = 2*pi*n./N; % 角频率(未归一化)
            
            % 绘制原信号音频--频谱图
            plot(app.originSIgSpectrum,w/pi,abs(fft_y));  % w归一化
            
            % 绘制原信号音频--相频图
            plot(app.originSIgPhaseFig,w/pi,unwrap(angle(fft_y)));
            
        end
    
        function results = updateTest(app)
            % 更新测试信号模块
            
            % 根据信号类型生成测试信号
            if app.sigType == 1
                app.testSig = app.originSig;
            elseif app.sigType==2
                app.testSig = app.originSig + app.noiseSig;
            end

            %app.isSetOrigin = 1; % 设置已经设置
            
            % 绘制原信号音频--时域图
            plot(app.testSigUIAxes,app.testSig);
            
            % 更新数据
            N = length(app.testSig); % 原信号点数
            fft_y = fft(app.testSig); % 计算fft
            
            n = linspace(0 , N-1,N); % 第n点
            w = 2*pi*n./N; % 角频率(未归一化)
            
            % 绘制原信号音频--频谱图
            plot(app.testSigSpectrum,w/pi,abs(fft_y));  % w归一化
            
            % 绘制原信号音频--相频图
            plot(app.testSIgPhaseFig,w/pi,unwrap(angle(fft_y)));
        end
    
        
        function results = redrawPanel(app)
            % 重新绘制Panel数据
            
            
        end
    
        function results = updateNoise(app)
            % 更新噪音信号模块
            
            % 代表已经设置噪音信号
            app.isSetNoise = 1; 
            
            % 绘制噪音信号音频--时域图
            plot(app.testSigUIAxes,app.noiseSig);
            
            % 更新数据
            N = length(app.noiseSig); % 噪音信号点数
            fft_y = fft(app.noiseSig); % 计算fft
            
            n = linspace(0 , N-1,N); % 第n点
            w = 2*pi*n./N; % 角频率(未归一化)
            
            % 绘制噪音信号--频谱图
            plot(app.testSigSpectrum,w/pi,abs(fft_y));  % w归一化
            
            % 赋值最终测试信号
            app.callingApp.testSig = app.originSig+app.noiseSig;
        end


        function [] = updateSigPanel(app, panelType)
            % 更新面板显示
            if panelType==1
                app.readAudioFilebtn.Visible = 'off';
                app.recordBtn.Visible='off';
                app.sigGenPanelBtn.Visible='on';
                
                app.noiseGenBtn.Visible = 'off'; % 不显示噪音模块
                
            elseif panelType==2
                app.readAudioFilebtn.Visible = 'on';
                app.recordBtn.Visible='on';
                app.sigGenPanelBtn.Visible='off';
                
                if app.sigType ==2 
                    app.noiseGenBtn.Visible='on';
                end
            end

            
        end
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, main)

            % 隐藏正弦函数叠加模块
            %set(app.SineSigGeneratePanel,'visible','off');
            % 初始化音频录制对象
            app.audioRecorderObj = audiorecorder(44100,16,1); 
            
            app.isSetOrigin = 0;
            app.isSetNoise = 0;
            app.isSetFilter = 0;
            app.sigType=1; % 默认为 1:多频正弦
            
            updateSigPanel(app,1);
            
            if nargin >=2
                % 更新呼叫的app
                app.callingApp=main; 
                app.sigType = main.sigType;
                % 如果设置了
                main.setTestSig
                if main.setTestSig == 1
                    app.originSig = main.originSig;
                    app.noiseSig = main.noiseSig;
                    updateOrigin(app); % 更新原信号
                    updateTest(app); % 更新测试信号
                end
                
            end
            
            
        end

        % Button pushed function: readAudioFilebtn
        function readAudioFilebtnPushed(app, event)
            
            % 打开文件选择框
            [file,path] = uigetfile({'*.wav';'*.ogg';'*.flac';'*.au';'*.aiff';'*.aif';'*.aifc';'*.mp3';'*.m4a';'*.mp4';'*.*'},...
                          'Surpported Audio File');  
            
            % 如果选择了文件那么打开音频文件
            if path ~= 0
                % 获得文件地址
                audio_file_path = [path,file]; 
                % 打开音频文件
                [app.originSig,Fs] = audioread(audio_file_path);
                Fs
                app.sigType = 2; % 数字音频
                app.noiseSig = zeros(size(app.originSig)); % 设置噪音为0
                updateOrigin(app); % 更新原信号
                updateTest(app); % 更新测试信号
                updateSigPanel(app,2); % 显示噪音模块
            end
        end

        % Value changed function: recordBtn
        function recordBtnValueChanged(app, event)
            value = app.recordBtn.Value;
           
            % 按下录制按钮
            if value== 1
                
                record(app.audioRecorderObj); % 开始录制
            % 结束录制
            else
                if ~isempty(app.audioRecorderObj)
                    app.originSig = getaudiodata(app.audioRecorderObj); % 获取录制数据
                    stop(app.audioRecorderObj); % 停止录制(貌似会销毁audioRecorder对象)
                    app.audioRecorderObj = audiorecorder; % 重新赋值避免空异常
                    app.sigType = 2; % 数字音频
                    app.noiseSig = zeros(size(app.originSig)); % 设置噪音为0
                    updateOrigin(app); % 更新
                    updateTest(app); % 更新测试信号
                    updateSigPanel(app,2); % 显示噪音模块
                end
            end          
        end

        % Button pushed function: originPlayBtn
        function originPlayBtnPushed(app, event)
            % 播放原信号(生成的信号)
            sound(app.originSig, 44100); 
        end

        % Button pushed function: sigGenPanelBtn
        function FreeGenerateSignal(app, event)
            
            
            multiSineGenApp(app);  % 打开正弦信号生成模块


        end

        % Callback function
        function closePanelBtnButtonPushed(app, event)
            set(app.SineSigGeneratePanel,'visible','off');
        end

        % Callback function
        function singQuitButtonPushed(app, event)
            % 退出控制面板
            set(app.SineSigGeneratePanel,'visible','off');
        end

        % Callback function
        function sineConfirmButtonPushed(app, event)
            set(app.SineSigGeneratePanel,'visible','on');
        end

        % Callback function
        function noiseFileRead(app, event)
                        
            % 打开文件选择框
            [file,path] = uigetfile({'*.wav';'*.ogg';'*.flac';'*.au';'*.aiff';'*.aif';'*.aifc';'*.mp3';'*.m4a';'*.mp4';'*.*'},...
                          'Surpported Audio File');  
            
            % 如果选择了文件那么打开音频文件
            if path ~= 0
                % 获得文件地址
                audio_file_path = [path,file]; 
                % 打开音频文件
                [app.noiseSig,Fs] = audioread(audio_file_path);
                updateNoise(app); % 更新噪音
            end
        end

        % Button pushed function: testPlayBtn
        function testPlayBtnCallback(app, event)
            sound(app.testSig,44100);
        end

        % Callback function
        function noiseGenerateCallback(app, event)
            noiseGenApp(app);
        end

        % Selection changed function: ButtonGroup
        function sigTypeChangeCallback(app, event)
            if app.multiSineTypeBtn == app.ButtonGroup.SelectedObject
                panelType= 1; % 多频正弦
            elseif app.digitalAudioTypeBtn == app.ButtonGroup.SelectedObject
                panelType= 2; % 数字音频
            end
            updateSigPanel(app,panelType);
        end

        % Button pushed function: noiseGenBtn
        function noiseGenBtnCallback(app, event)
            noiseGenApp(app);
        end

        % Button pushed function: Button_9
        function sigGenApply(app, event)
            app.sigType
            app.callingApp.originSig = app.originSig;
            app.callingApp.setTestSig = 1;
            if app.sigType==1
                app.callingApp.sigType=1;
                app.callingApp.testSig=app.originSig;
            elseif app.sigType==2
                
                
                app.callingApp.sigType=2;
                app.callingApp.originSig = app.originSig;
                app.callingApp.testSig = app.originSig + app.noiseSig; % 包括噪音信号
                app.callingApp.noiseSig = app.noiseSig;
            end
            app.callingApp.Lamp.Color = 'g';
        end

        % Button pushed function: Button_10
        function sigGenConfirm(app, event)
            sigGenApply(app);
            
            delete(app);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = '测试信号生成模块';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '时域图')
            xlabel(app.UIAxes, 'n')
            ylabel(app.UIAxes, '信号幅值')
            app.readAudioFilebtn = uibutton(app.UIFigure, 'push');
            app.readAudioFilebtn.ButtonPushedFcn = createCallbackFcn(app, @readAudioFilebtnPushed, true);
            app.readAudioFilebtn.Text = '从文件中读取';
            app.sigGenPanelBtn = uibutton(app.UIFigure, 'push');
            app.sigGenPanelBtn.ButtonPushedFcn = createCallbackFcn(app, @FreeGenerateSignal, true);
            app.sigGenPanelBtn.Text = '正弦信号生成';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '测试信号生成模块';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '最终测试信号';
            app.recordBtn = uibutton(app.UIFigure, 'state');
            app.recordBtn.ValueChangedFcn = createCallbackFcn(app, @recordBtnValueChanged, true);
            app.recordBtn.Text = '语音输入';
            app.originSIgSpectrum = uiaxes(app.UIFigure);
            title(app.originSIgSpectrum, '幅频图')
            xlabel(app.originSIgSpectrum, 'w/\pi')
            ylabel(app.originSIgSpectrum, '信号幅值')
            app.originPlayBtn = uibutton(app.UIFigure, 'push');
            app.originPlayBtn.ButtonPushedFcn = createCallbackFcn(app, @originPlayBtnPushed, true);
            app.originPlayBtn.Text = '播放';
            app.originSIgPhaseFig = uiaxes(app.UIFigure);
            title(app.originSIgPhaseFig, '相频图')
            xlabel(app.originSIgPhaseFig, 'w/\pi')
            ylabel(app.originSIgPhaseFig, '信号相位')
            app.testSigUIAxes = uiaxes(app.UIFigure);
            title(app.testSigUIAxes, '时域图')
            xlabel(app.testSigUIAxes, 'n')
            ylabel(app.testSigUIAxes, '信号幅值')
            app.testSigSpectrum = uiaxes(app.UIFigure);
            title(app.testSigSpectrum, '幅频图')
            xlabel(app.testSigSpectrum, 'w/\pi')
            ylabel(app.testSigSpectrum, '信号幅值')
            app.testPlayBtn = uibutton(app.UIFigure, 'push');
            app.testPlayBtn.ButtonPushedFcn = createCallbackFcn(app, @testPlayBtnCallback, true);
            app.testPlayBtn.Text = '播放';
            app.ButtonGroup = uibuttongroup(app.UIFigure);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @sigTypeChangeCallback, true);
            app.ButtonGroup.Title = '信号类型选择';
            app.multiSineTypeBtn = uiradiobutton(app.ButtonGroup);
            app.multiSineTypeBtn.Text = '多频正弦';
            app.multiSineTypeBtn.Value = true;
            app.digitalAudioTypeBtn = uiradiobutton(app.ButtonGroup);
            app.digitalAudioTypeBtn.Text = '数字音频';
            app.noiseGenBtn = uibutton(app.UIFigure, 'push');
            app.noiseGenBtn.ButtonPushedFcn = createCallbackFcn(app, @noiseGenBtnCallback, true);
            app.noiseGenBtn.Text = '噪音生成';
            app.Button_9 = uibutton(app.UIFigure, 'push');
            app.Button_9.ButtonPushedFcn = createCallbackFcn(app, @sigGenApply, true);
            app.Button_9.Text = '应用';
            app.Button_10 = uibutton(app.UIFigure, 'push');
            app.Button_10.ButtonPushedFcn = createCallbackFcn(app, @sigGenConfirm, true);
            app.Button_10.Text = '确定';
            app.testSIgPhaseFig = uiaxes(app.UIFigure);
            title(app.testSIgPhaseFig, '相频图')
            xlabel(app.testSIgPhaseFig, 'w/\pi')
            ylabel(app.testSIgPhaseFig, '信号相位')
        end
    end

    methods (Access = public)

        % Construct app
        function app = testSigGenApp(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end